classdef GridPulser70127420_0_MATLABv1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        StartUpProgramPanel           matlab.ui.container.Panel
        DataLoggingPanel              matlab.ui.container.Panel
        Panel                         matlab.ui.container.Panel
        GuidesCheckBox                matlab.ui.control.CheckBox
        AutoAxisCheckBox              matlab.ui.control.CheckBox
        ClearBufferButton             matlab.ui.control.Button
        CurrentLowLabel               matlab.ui.control.Label
        EditField_12                  matlab.ui.control.NumericEditField
        CurrentHighLabel              matlab.ui.control.Label
        EditField_11                  matlab.ui.control.NumericEditField
        VoltageLowLabel               matlab.ui.control.Label
        EditField_10                  matlab.ui.control.NumericEditField
        VoltageHighLabel              matlab.ui.control.Label
        EditField_9                   matlab.ui.control.NumericEditField
        WindowsLabel                  matlab.ui.control.Label
        EditField_8                   matlab.ui.control.NumericEditField
        UIAxes                        matlab.ui.control.UIAxes
        MeasuredParametersPanel       matlab.ui.container.Panel
        TextArea_11                   matlab.ui.control.TextArea
        TextArea_9                    matlab.ui.control.TextArea
        GridVoltageActualVLabel       matlab.ui.control.Label
        TextArea_8                    matlab.ui.control.TextArea
        FilImpedanceohmsLabel         matlab.ui.control.Label
        TextArea_7                    matlab.ui.control.TextArea
        TextArea_6                    matlab.ui.control.TextArea
        TextArea_5                    matlab.ui.control.TextArea
        InputVoltageActualVLabel      matlab.ui.control.Label
        TextArea_4                    matlab.ui.control.TextArea
        FilPowerActualWLabel          matlab.ui.control.Label
        TextArea_3                    matlab.ui.control.TextArea
        FilCurrentActualALabel        matlab.ui.control.Label
        TextArea_2                    matlab.ui.control.TextArea
        FilVoltageActualVLabel        matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        SetpointsPanel                matlab.ui.container.Panel
        RealfilimpedanceohmsTextArea  matlab.ui.control.TextArea
        RealfilimpedanceohmsTextAreaLabel  matlab.ui.control.Label
        SSresohmsEditField            matlab.ui.control.NumericEditField
        SSresohmsEditFieldLabel       matlab.ui.control.Label
        SoftstartBypassSwitch         matlab.ui.control.Switch
        SoftstartBypassSwitchLabel    matlab.ui.control.Label
        DesGridVoltageLabel           matlab.ui.control.Label
        V761066VLabel                 matlab.ui.control.Label
        EditField_13                  matlab.ui.control.NumericEditField
        MaxFilCurrentTripLabel        matlab.ui.control.Label
        MaxFilVoltageTripLabel        matlab.ui.control.Label
        MaxInputVoltageTripLabel      matlab.ui.control.Label
        DesFilCurrentLabel            matlab.ui.control.Label
        DesFilVoltageLabel            matlab.ui.control.Label
        ALabel_2                      matlab.ui.control.Label
        EditField_5                   matlab.ui.control.NumericEditField
        VLabel_3                      matlab.ui.control.Label
        EditField_4                   matlab.ui.control.NumericEditField
        V20V40VLabel                  matlab.ui.control.Label
        EditField_3                   matlab.ui.control.NumericEditField
        ANOTUSEDLabel                 matlab.ui.control.Label
        EditField_2                   matlab.ui.control.NumericEditField
        V197V81VLabel                 matlab.ui.control.Label
        EditField                     matlab.ui.control.NumericEditField
        PowerSupplyControlPanel       matlab.ui.container.Panel
        ResetTripLEDsButton           matlab.ui.control.Button
        OutputOverVoltageLamp         matlab.ui.control.Lamp
        OutputOverVoltageLampLabel    matlab.ui.control.Label
        GridEnableSwitch              matlab.ui.control.ToggleSwitch
        GridEnableSwitchLabel         matlab.ui.control.Label
        FilamentEnableSwitch          matlab.ui.control.ToggleSwitch
        FilamentEnableSwitchLabel     matlab.ui.control.Label
        InputOverVoltageLamp          matlab.ui.control.Lamp
        InputOverVoltageLampLabel     matlab.ui.control.Label
        OutputOverCurrentLamp         matlab.ui.control.Lamp
        OutputOverCurrentLampLabel    matlab.ui.control.Label
        Knob                          matlab.ui.control.DiscreteKnob
        CommunicationControlPanel     matlab.ui.container.Panel
        UpdateCOMButton               matlab.ui.control.Button
        DropDown                      matlab.ui.control.DropDown
        DisconnectSerialButton        matlab.ui.control.Button
        StatusLamp                    matlab.ui.control.Lamp
        ConnectSerialButton           matlab.ui.control.Button
    end

    
    properties (Access = public)
        
    end
    
    properties (Access = private)
        t       % timer object
        t2      %plot timer object
        V_out   %output voltage array
        I_out   %output current array
        t_array %time array
        V_out_inst=0        %inst. output voltage
        I_out_inst=0        %inst. output current
        t2_updaterate = 1   %plot update rate in seconds
        control_byte_sent   %the control byte sent to arduino
        control_byte_returned %the control byte from arduino
        readings_counter=0
        NUM_readings = 20
        reading1=0
        reading2=0
        reading3=0
        
        %is current is 1/.066, then each count is 2.996/.064/2^12= 11.4mA
        Current_offset_counts=0; %6.18.20 calibration 0
        Hall_offset = 0; %6.18.20 calibration 0
        
        ADC_Vref = 3.25;    %6.18.20 calibration 3.25
        ADC_offset = 0.02; %6.18.20 calibration 0.02
        
    end
    
    methods (Access = private)
        
        function heartbeat(app, obj, event)
            
            app.write_status; %write status,send 4 setpoint, get status echo,
                              %check heartbeat, write setpoints to arduino
            app.status_compare; %check fil voltage & current vs. thresholds
            
         end
        
        function getADCs(app, obj, event)
            global s;
            %Code to receive 4 ADC values sent by Arduino whenever write
            %command is sent from MATLAB.
            V_bus_counts =        str2double(readline(s));
            V_fil_counts =        str2double(readline(s));
            I_fil_counts =        str2double(readline(s));
            V_grid_counts =       str2double(readline(s));
            
%           app.TextArea_4.Value %Vbus readback field
%           app.TextArea_8.Value %Vgrid readback field

            app.TextArea_4.Value = num2str(V_bus_counts*50/1024,'%-4.2f');
            app.V_out_inst = V_fil_counts*10/1024;
            app.I_out_inst = I_fil_counts*25/1024;
            % Vgrid readback is saturated in the 100V-106.5V range
            app.TextArea_8.Value = num2str(V_grid_counts*100/1024,'%-4.1f');

            %Updating numerical display of ADC values & derived quantities
            app.TextArea.Value =   num2str(app.V_out_inst,'%-4.2f');
            app.TextArea_2.Value = num2str(app.I_out_inst,'%-3.1f');
            app.TextArea_3.Value = num2str(app.V_out_inst*app.I_out_inst,'%-4.1f');
            app.TextArea_7.Value = num2str(app.V_out_inst/app.I_out_inst,'%-4.1f');
            app.RealfilimpedanceohmsTextArea.Value = ...
                num2str((app.V_out_inst/app.I_out_inst) - ... 
                    app.SSresohmsEditField.Value,'%-4.1f');

            % app.TextArea_4.Value = [num2str(vbus,'%-5.2f') ' V'];
                
        end
            
        function display_plot(app, obj, event)
            app.V_out(1:end-1)  = app.V_out(2:end);
            app.V_out(end)      = app.V_out_inst;
        
            app.I_out(1:end-1)  = app.I_out(2:end);
            app.I_out(end)      = app.I_out_inst;
            yyaxis(app.UIAxes,'left');
            plot(app.UIAxes,app.t_array,app.V_out);
            yyaxis(app.UIAxes,'right');
            plot(app.UIAxes,app.t_array,app.I_out);
            ylabel(app.UIAxes,'Output Current (A)');
            if app.AutoAxisCheckBox.Value==0
                xlim(app.UIAxes,[-app.EditField_8.Value 0]);
            else
            end
            
            if app.GuidesCheckBox.Value ==1
                yyaxis(app.UIAxes,'left');
                temp = axis(app.UIAxes);
                avg_V = mean(app.V_out(round(end-app.t2_updaterate*abs(temp(1))):end));
                avg_I = mean(app.I_out(round(end-app.t2_updaterate*abs(temp(1))):end));
                hold(app.UIAxes,'on');
                plot(app.UIAxes,[temp(1) temp(2)],[avg_V*1.005 avg_V*1.005],'b--');
                plot(app.UIAxes,[temp(1) temp(2)],[avg_V*.995 avg_V*.995],'b--');
                
                yyaxis(app.UIAxes,'right');
                plot(app.UIAxes,[temp(1) temp(2)],[avg_I*1.005 avg_I*1.005],'r--');
                plot(app.UIAxes,[temp(1) temp(2)],[avg_I*.995 avg_I*.995],'r--');
                
                hold(app.UIAxes,'off');
            end
            pause(0.01);
        end
        
        function status_compare(app, obj, event)

            % Disable filament and reset to minimum if OVV/OVC trips.
            if (app.EditField_5.Value < app.I_out_inst) || (app.EditField_4.Value < app.V_out_inst)
                app.FilamentEnableSwitch.Value = 'Off';
                app.EditField.Value = 1.97;
                if (app.EditField_5.Value < app.I_out_inst)
                    app.OutputOverCurrentLamp.Color = [1 0 0]; %red
                end
                if (app.EditField_4.Value < app.V_out_inst)
                    app.OutputOverVoltageLamp.Color = [1 0 0]; %red
                end
            end    

        end
        
      
         function write_status(app)
            global s;

            % Handling input voltage to counts conversion.

            % Filament voltage conversion, user input volts --> counts:
            v_f_des = app.EditField.Value;
            n1_c = ceil((-5.761002E-4+sqrt((5.761002E-4^2)+4*1.509E-7* ...
                (7.42763501E-2-(1/v_f_des))))/(2*-1.509E-7));
            if n1_c > 1023
                n1_c = 1023;
            elseif n1_c < 0
                n1_c = 0;
            end
            
            % Grid voltage conversion, user input volts --> counts:
            v_g_des = app.EditField_13.Value;
            n3_c = ceil((-1.4582626E-4+sqrt(2.127E-8+1.0492E-7* ...
                (9.46099201E-3-(1/v_g_des))))/-5.426E-8);
            if n3_c > 1023
                n3_c = 1023;
            elseif n3_c < 0
                n3_c = 0;
            end

            % Previous pure counts input.
%           app.EditField_2.Value; %desired fil current input field
            n1 = num2str(n1_c);
            n2 = num2str(strcmp('On',app.FilamentEnableSwitch.Value));
            n3 = num2str(n3_c);
            n4 = num2str(strcmp('Off',app.GridEnableSwitch.Value));
            n5 = num2str(strcmp('On',app.SoftstartBypassSwitch.Value));

            write(s,['!' n1 ' ' n2 ' ' n3 ' ' n4 ' ' n5 ','],"char");

%             write(s,['!1023 0 1023 1 0,'],"char");%output voltage setpoint
            app.getADCs;


%             pause(0.001);
        end
        
        % Currently unused.
        function counts = toCounts(app,Vdes,bits,low,high)
            %V out: 'low' V to 'high' V with 'bits' bit resolution. 2.2 is range
            counts = floor((Vdes-low)/((high-low)/2^bits));
            if counts > 2^bits-1
                counts = 2^bits-1;
            elseif counts < 0
                counts = 0;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: UpdateCOMButton
        function UpdateCOMButtonPushed(app, event)
            
            ttyList={};
            portOrder =[];
            [stat, result] = system('mode');
            % identify the COM ports in the output string and format in cell array
            portList = regexp(result,'COM\d+:','match');
            k=0;
            for p = portList
                k=k+1;
                portNum = regexp(p{1},'\d+','match');
                portName = regexp(p{1},'COM\d+','match');
                ttyList = [ttyList; {portNum{1}, portName{1}}];
                portOrder = [portOrder str2num(portNum{1})];
            end
            % return the list sorted with largest number first
            [p2,i]=sort(portOrder,2,'descend');
            a = ttyList(i,:);
            k=size(a);
            if k(1)>0
                for n=1:k(1)
                    a(n,2);
                end
                app.DropDown.Items=a(:,2);
            else
                app.DropDown.Items='None';
            end
        end

        % Button pushed function: ConnectSerialButton
        function ConnectSerialButtonPushed(app, event)
            global s;
            try
                s = serialport(app.DropDown.Value,38400);
                configureTerminator(s,'CR/LF'); %set comma as terminator (44) (was 'LF')
                app.StatusLamp.Color = [0 1 0]; %green
            catch 
                app.StatusLamp.Color = [1 0 0]; %red
            end
            pause(1);
            
            % Send disable condition to FS/GP on start-up, reset user fields
            write(s,['!1023 0 1023 1,'],"char");%output voltage setpoint
            app.getADCs;
            app.FilamentEnableSwitch.Value = 'Off';
            app.GridEnableSwitch.Value = 'Off';
            app.EditField.Value = 1.97;
            app.EditField_13.Value = 7.6;

            app.t = timer;
            app.t.TimerFcn = {@app.heartbeat};
            app.t.Period = 0.5;
            %app.t.BusyMode = 'queue';
            app.t.ExecutionMode = 'fixedRate';
            
            %plotting timer
            app.t2 = timer;
            app.t2.TimerFcn = {@app.display_plot};
            app.t2.Period = app.t2_updaterate;
            app.t2.StartDelay = .99;
            app.t2.ExecutionMode = 'fixedRate';
            app.t2.BusyMode = 'queue';
            app.V_out = NaN(60*60*10/app.t2_updaterate,1); %10 hours worth of data
            app.I_out = NaN(60*60*10/app.t2_updaterate,1); %10 hours worth of data
            app.t_array = -(numel(app.V_out)-1)*app.t2_updaterate:app.t2_updaterate:0;
            start(app.t2); %start plotting
            start(app.t); %start heart beat
            
        end

        % Button pushed function: DisconnectSerialButton
        function DisconnectSerialButtonPushed(app, event)
            global s;
            % Disabling FS/GP module and setting values to minimum
            app.FilamentEnableSwitch.Value = 'Off';
            app.GridEnableSwitch.Value = 'Off';
            app.EditField.Value = 1.97;
            app.EditField_13.Value = 7.6;

            try
                write(s,['!1023 0 1023 1,'],"char"); %output voltage setpoint
                app.getADCs;
                stop(app.t);
                stop(app.t2);
                delete(timerfind);
            catch
            end

            clear global;

            app.StatusLamp.Color = [1 0 0]; %red
        end

        % Callback function
        function GetVoltage1ButtonPushed(app, event)
            
        end

        % Callback function
        function SetDesiredVoltageButtonPushed(app, event)
            
        end

        % Button pushed function: ClearBufferButton
        function ClearBufferButtonPushed(app, event)
            app.V_out = NaN(60*60*10/app.t2_updaterate,1);%10 hours worth of data
            app.I_out = NaN(60*60*10/app.t2_updaterate,1);%10 hours worth of data
        
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            DisconnectSerialButtonPushed(app, event);

            delete(app)
        end

        % Button pushed function: ResetTripLEDsButton
        function TripResetButtonPushed(app, event)
            app.OutputOverCurrentLamp.Color = [0 1 0]; %green
            app.OutputOverVoltageLamp.Color = [0 1 0]; %green
            app.InputOverVoltageLamp.Color  = [0 1 0]; %green
        end

        % Value changed function: SoftstartBypassSwitch
        function SoftstartBypassSwitchValueChanged(app, event)
            value = app.SoftstartBypassSwitch.Value;
            if (strcmpi(value,'Off'))
                app.EditField.Value = 1.97;
                app.EditField.Enable = 'Off';
            else
                app.EditField.Enable = 'On';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CommunicationControlPanel = uipanel(app.UIFigure);
            app.CommunicationControlPanel.Title = 'Communication Control';
            app.ConnectSerialButton = uibutton(app.CommunicationControlPanel, 'push');
            app.ConnectSerialButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectSerialButtonPushed, true);
            app.ConnectSerialButton.Text = 'Connect Serial';
            app.StatusLamp = uilamp(app.CommunicationControlPanel);
            app.DisconnectSerialButton = uibutton(app.CommunicationControlPanel, 'push');
            app.DisconnectSerialButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectSerialButtonPushed, true);
            app.DisconnectSerialButton.Text = 'Disconnect Serial';
            app.DropDown = uidropdown(app.CommunicationControlPanel);
            app.DropDown.Items = {'Unknown'};
            app.DropDown.Value = 'Unknown';
            app.UpdateCOMButton = uibutton(app.CommunicationControlPanel, 'push');
            app.UpdateCOMButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateCOMButtonPushed, true);
            app.UpdateCOMButton.Text = 'Update COM';
            app.PowerSupplyControlPanel = uipanel(app.UIFigure);
            app.PowerSupplyControlPanel.Title = 'Power Supply Control';
            app.Knob = uiknob(app.PowerSupplyControlPanel, 'discrete');
            app.Knob.Items = {'Const. V', 'Const. I', 'Program'};
            app.Knob.Value = 'Const. V';
            app.OutputOverCurrentLampLabel = uilabel(app.PowerSupplyControlPanel);
            app.OutputOverCurrentLampLabel.Text = 'Output Over Current';
            app.OutputOverCurrentLamp = uilamp(app.PowerSupplyControlPanel);
            app.InputOverVoltageLampLabel = uilabel(app.PowerSupplyControlPanel);
            app.InputOverVoltageLampLabel.Text = 'Input Over Voltage';
            app.InputOverVoltageLamp = uilamp(app.PowerSupplyControlPanel);
            app.FilamentEnableSwitchLabel = uilabel(app.PowerSupplyControlPanel);
            app.FilamentEnableSwitchLabel.Text = 'Filament Enable';
            app.FilamentEnableSwitch = uiswitch(app.PowerSupplyControlPanel, 'toggle');
            app.GridEnableSwitchLabel = uilabel(app.PowerSupplyControlPanel);
            app.GridEnableSwitchLabel.Text = 'Grid Enable';
            app.GridEnableSwitch = uiswitch(app.PowerSupplyControlPanel, 'toggle');
            app.OutputOverVoltageLampLabel = uilabel(app.PowerSupplyControlPanel);
            app.OutputOverVoltageLampLabel.Text = 'Output Over Voltage';
            app.OutputOverVoltageLamp = uilamp(app.PowerSupplyControlPanel);
            app.ResetTripLEDsButton = uibutton(app.PowerSupplyControlPanel, 'push');
            app.ResetTripLEDsButton.ButtonPushedFcn = createCallbackFcn(app, @TripResetButtonPushed, true);
            app.ResetTripLEDsButton.Text = 'Reset Trip LEDs';
            app.SetpointsPanel = uipanel(app.UIFigure);
            app.SetpointsPanel.Title = 'Setpoints';
            app.EditField = uieditfield(app.SetpointsPanel, 'numeric');
            app.EditField.Limits = [1.97 8.1];
            app.EditField.ValueDisplayFormat = '%5.4g';
            app.EditField.Enable = 'off';
            app.EditField.Value = 1.97;
            app.V197V81VLabel = uilabel(app.SetpointsPanel);
            app.V197V81VLabel.Text = 'V (1.97V-8.1V)';
            app.EditField_2 = uieditfield(app.SetpointsPanel, 'numeric');
            app.EditField_2.ValueDisplayFormat = '%5.3g';
            app.ANOTUSEDLabel = uilabel(app.SetpointsPanel);
            app.ANOTUSEDLabel.Text = 'A (NOT USED)';
            app.EditField_3 = uieditfield(app.SetpointsPanel, 'numeric');
            app.EditField_3.Limits = [-Inf 40];
            app.EditField_3.ValueDisplayFormat = '%5.3g';
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Value = 40;
            app.V20V40VLabel = uilabel(app.SetpointsPanel);
            app.V20V40VLabel.Text = 'V (20V-40V)';
            app.EditField_4 = uieditfield(app.SetpointsPanel, 'numeric');
            app.EditField_4.Limits = [0 20];
            app.EditField_4.ValueDisplayFormat = '%5.3g';
            app.EditField_4.Value = 8;
            app.VLabel_3 = uilabel(app.SetpointsPanel);
            app.VLabel_3.Text = 'V';
            app.EditField_5 = uieditfield(app.SetpointsPanel, 'numeric');
            app.EditField_5.Limits = [0 20];
            app.EditField_5.ValueDisplayFormat = '%5.3g';
            app.EditField_5.Value = 2.5;
            app.ALabel_2 = uilabel(app.SetpointsPanel);
            app.ALabel_2.Text = 'A';
            app.DesFilVoltageLabel = uilabel(app.SetpointsPanel);
            app.DesFilVoltageLabel.Text = 'Des. Fil. Voltage';
            app.DesFilCurrentLabel = uilabel(app.SetpointsPanel);
            app.DesFilCurrentLabel.Text = 'Des. Fil. Current';
            app.MaxInputVoltageTripLabel = uilabel(app.SetpointsPanel);
            app.MaxInputVoltageTripLabel.Text = 'Max Input Voltage Trip';
            app.MaxFilVoltageTripLabel = uilabel(app.SetpointsPanel);
            app.MaxFilVoltageTripLabel.Text = 'Max Fil. Voltage Trip';
            app.MaxFilCurrentTripLabel = uilabel(app.SetpointsPanel);
            app.MaxFilCurrentTripLabel.Text = 'Max Fil. Current Trip';
            app.EditField_13 = uieditfield(app.SetpointsPanel, 'numeric');
            app.EditField_13.Limits = [7.6 106.6];
            app.EditField_13.ValueDisplayFormat = '%5.4g';
            app.EditField_13.Value = 7.6;
            app.V761066VLabel = uilabel(app.SetpointsPanel);
            app.V761066VLabel.Text = 'V (7.6-106.6V)';
            app.DesGridVoltageLabel = uilabel(app.SetpointsPanel);
            app.DesGridVoltageLabel.Text = 'Des. Grid Voltage';
            app.SoftstartBypassSwitchLabel = uilabel(app.SetpointsPanel);
            app.SoftstartBypassSwitchLabel.Text = 'Soft-start Bypass';
            app.SoftstartBypassSwitch = uiswitch(app.SetpointsPanel, 'slider');
            app.SoftstartBypassSwitch.ValueChangedFcn = createCallbackFcn(app, @SoftstartBypassSwitchValueChanged, true);
            app.SSresohmsEditFieldLabel = uilabel(app.SetpointsPanel);
            app.SSresohmsEditFieldLabel.Text = 'SS res. (ohms)';
            app.SSresohmsEditField = uieditfield(app.SetpointsPanel, 'numeric');
            app.SSresohmsEditField.Value = 2;
            app.RealfilimpedanceohmsTextAreaLabel = uilabel(app.SetpointsPanel);
            app.RealfilimpedanceohmsTextAreaLabel.Text = {'Real fil. impedance'; '(ohms)'};
            app.RealfilimpedanceohmsTextArea = uitextarea(app.SetpointsPanel);
            app.MeasuredParametersPanel = uipanel(app.UIFigure);
            app.MeasuredParametersPanel.Title = 'Measured Parameters';
            app.TextArea = uitextarea(app.MeasuredParametersPanel);
            app.TextArea.Editable = 'off';
            app.FilVoltageActualVLabel = uilabel(app.MeasuredParametersPanel);
            app.FilVoltageActualVLabel.Text = 'Fil. Voltage Actual (V)';
            app.TextArea_2 = uitextarea(app.MeasuredParametersPanel);
            app.TextArea_2.Editable = 'off';
            app.FilCurrentActualALabel = uilabel(app.MeasuredParametersPanel);
            app.FilCurrentActualALabel.Text = 'Fil. Current Actual (A)';
            app.TextArea_3 = uitextarea(app.MeasuredParametersPanel);
            app.TextArea_3.Editable = 'off';
            app.FilPowerActualWLabel = uilabel(app.MeasuredParametersPanel);
            app.FilPowerActualWLabel.Text = 'Fil. Power Actual (W)';
            app.TextArea_4 = uitextarea(app.MeasuredParametersPanel);
            app.TextArea_4.Editable = 'off';
            app.InputVoltageActualVLabel = uilabel(app.MeasuredParametersPanel);
            app.InputVoltageActualVLabel.Text = 'Input Voltage Actual (V)';
            app.TextArea_5 = uitextarea(app.MeasuredParametersPanel);
            app.TextArea_5.Editable = 'off';
            app.TextArea_5.Value = {'< +-3%'};
            app.TextArea_6 = uitextarea(app.MeasuredParametersPanel);
            app.TextArea_6.Editable = 'off';
            app.TextArea_6.Value = {'< 5-10%'};
            app.TextArea_7 = uitextarea(app.MeasuredParametersPanel);
            app.TextArea_7.Editable = 'off';
            app.FilImpedanceohmsLabel = uilabel(app.MeasuredParametersPanel);
            app.FilImpedanceohmsLabel.Text = 'Fil. Impedance (ohms)';
            app.TextArea_8 = uitextarea(app.MeasuredParametersPanel);
            app.TextArea_8.Editable = 'off';
            app.GridVoltageActualVLabel = uilabel(app.MeasuredParametersPanel);
            app.GridVoltageActualVLabel.Text = 'Grid Voltage Actual (V)';
            app.TextArea_9 = uitextarea(app.MeasuredParametersPanel);
            app.TextArea_9.Editable = 'off';
            app.TextArea_9.Value = {'Saturates above 100V'};
            app.TextArea_11 = uitextarea(app.MeasuredParametersPanel);
            app.TextArea_11.Editable = 'off';
            app.TextArea_11.Value = {'< +-2%'};
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel);
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Output Voltage (V)')
            app.EditField_8 = uieditfield(app.Panel, 'numeric');
            app.EditField_8.Limits = [3 Inf];
            app.EditField_8.ValueDisplayFormat = '%5.3g';
            app.EditField_8.Value = 10;
            app.WindowsLabel = uilabel(app.Panel);
            app.WindowsLabel.Text = 'Window (s)';
            app.EditField_9 = uieditfield(app.Panel, 'numeric');
            app.EditField_9.ValueDisplayFormat = '%5.3g';
            app.EditField_9.Visible = 'off';
            app.EditField_9.Value = 10;
            app.VoltageHighLabel = uilabel(app.Panel);
            app.VoltageHighLabel.Visible = 'off';
            app.VoltageHighLabel.Text = 'Voltage High';
            app.EditField_10 = uieditfield(app.Panel, 'numeric');
            app.EditField_10.ValueDisplayFormat = '%5.3g';
            app.EditField_10.Visible = 'off';
            app.VoltageLowLabel = uilabel(app.Panel);
            app.VoltageLowLabel.Visible = 'off';
            app.VoltageLowLabel.Text = 'Voltage Low';
            app.EditField_11 = uieditfield(app.Panel, 'numeric');
            app.EditField_11.ValueDisplayFormat = '%5.3g';
            app.EditField_11.Visible = 'off';
            app.EditField_11.Value = 10;
            app.CurrentHighLabel = uilabel(app.Panel);
            app.CurrentHighLabel.Visible = 'off';
            app.CurrentHighLabel.Text = 'Current High';
            app.EditField_12 = uieditfield(app.Panel, 'numeric');
            app.EditField_12.ValueDisplayFormat = '%5.3g';
            app.EditField_12.Visible = 'off';
            app.CurrentLowLabel = uilabel(app.Panel);
            app.CurrentLowLabel.Visible = 'off';
            app.CurrentLowLabel.Text = 'Current Low';
            app.ClearBufferButton = uibutton(app.Panel, 'push');
            app.ClearBufferButton.ButtonPushedFcn = createCallbackFcn(app, @ClearBufferButtonPushed, true);
            app.ClearBufferButton.Text = 'Clear Buffer';
            app.AutoAxisCheckBox = uicheckbox(app.Panel);
            app.AutoAxisCheckBox.Text = 'Auto Axis';
            app.AutoAxisCheckBox.Value = true;
            app.GuidesCheckBox = uicheckbox(app.Panel);
            app.GuidesCheckBox.Text = '1% Guides';
            app.GuidesCheckBox.Value = true;
            app.DataLoggingPanel = uipanel(app.UIFigure);
            app.DataLoggingPanel.Title = 'Data Logging';
            app.StartUpProgramPanel = uipanel(app.UIFigure);
            app.StartUpProgramPanel.Title = 'Start-Up Program';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GridPulser70127420_0_MATLABv1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end